classdef awgdisp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWGMONITORAPPUIFigure        matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIadvSequencerTable          matlab.ui.control.Table
        sourceButtonGroup            matlab.ui.container.ButtonGroup
        qctkButton                   matlab.ui.control.RadioButton
        simulatorButton              matlab.ui.control.RadioButton
        statusEditFieldLabel         matlab.ui.control.Label
        statusEditField              matlab.ui.control.EditField
        UIprogramTable               matlab.ui.control.Table
        channelpairButtonGroup       matlab.ui.container.ButtonGroup
        ABButton                     matlab.ui.control.RadioButton
        CDButton                     matlab.ui.control.RadioButton
        UIsequencerTable             matlab.ui.control.Table
        AdvancedSequencerTableLabel  matlab.ui.control.Label
        SelectedTableinSequencerTablesLabel  matlab.ui.control.Label
        refreshButton                matlab.ui.control.Button
        AWGVmultiplierLabel          matlab.ui.control.Label
        AWGVmultiplierEditField      matlab.ui.control.NumericEditField
        AWGtmultiplierLabel          matlab.ui.control.Label
        AWGtmultiplierEditField      matlab.ui.control.NumericEditField
        getfigureButton              matlab.ui.control.Button
    end


    properties (Access = private) %######################################################################################################
      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
% matlab (vers 2018a) app to DISPLAY SEQUENCER TABLES AND                  %
% WAFEFORMS in qctoolkit and on the Tabor AWG simulatar                    %
% -------------------------------------------------------------------------%
% user preferences can be edited directly below                            %
% -------------------------------------------------------------------------%
% App written by Marcel Meyer 08|2018          marcel.meyer1@rwth-aachen.de%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % user preferences / settings / options
        AWG_t_multiplier_standard = 0.5e-9;
        AWG_V_multiplier_standard = 0; % O is equal to 1
        
        timeoutTime = duration(00,00,60);
        
        % connection to qctoolkit
        hws; %hardware setup 
        known_awgs;
        known_programs;
        channel_pair_index;
        program_name;
        
        % all current button and table selections and multipliers
        chanAB_selected;
        qctk_selected;
        selected_sub_seq_table;
        selected_sub_seq_table_entry;
        VMultiplier = 1;
        tMultiplier = 1;
        
        % all status messages
        msg_list = {'', '', '', ''};
        
        % sequence tables
        qctk_advSeqTable;
        qctk_stdSeqTable;
        sim_advSeqTable;
        sim_stdSeqTable;
    end

    methods (Access = private)
        
        % --------------------------------------function to display messages (e.g. busy or ready) --------------------------------------------
        function infodisp(app, status, msg)
            if nargin < 3
                msg = '';
            else
                app.msg_list{end+1} = msg;
            end
                
            
            if status==0
                status_string = 'busy';
            elseif status==1
                status_string = 'ready';
            end

            app.statusEditField.Value = [status_string ' -- ' app.msg_list{end-3:end}];
            drawnow;
        end


        % ---------------------------------------update program table-------------------------------------------------------------------------
        function update_program_table(app)
            
          %info message 'busy'
          app.infodisp(0);
          
          % get data
          if app.chanAB_selected
            app.channel_pair_index = 1;
          else
            app.channel_pair_index = 2;
          end
          app.known_programs{app.channel_pair_index} = util.py.py2mat(py.getattr(app.known_awgs{app.channel_pair_index}, '_known_programs'));
          app.known_programs = fieldnames(app.known_programs{app.channel_pair_index});
          
          %disp data
          app.UIprogramTable.Data = app.known_programs;
          
          %clear seq tables and wf plot
          app.UIadvSequencerTable.Data = [];
          app.UIsequencerTable.Data = [];
          cla(app.UIAxes);
          
          % disp 'ready'
          app.infodisp(1);
        end


        % ------------------------------------------------------update advanced sequencer table--------------------------------------------------    
        function update_adv_sequence_table(app)
          app.infodisp(0);
          
          % load adv sequ table
          if app.qctk_selected
            app.qctk_advSeqTable = qc.get_sequence_table(app.program_name, true);
          else
            app.qctk_advSeqTable = qc.get_sequence_table_from_simulator(app.program_name, true);
          end
          
          % clear table and check if all conditions to display table are fullfilled
          app.UIadvSequencerTable.Data = [];
          if ~isempty(app.qctk_advSeqTable{app.channel_pair_index})
              dispConditions = true;
          else
              dispConditions = false;
          end
          
          % disp table
          if dispConditions
            for k = 1:length(app.qctk_advSeqTable{app.channel_pair_index})
              app.UIadvSequencerTable.Data(k,:) = cell2mat(app.qctk_advSeqTable{app.channel_pair_index}{k});
            end
          else
            % if conditions not fullfilled
            app.UIadvSequencerTable.Data = [];
            app.infodisp(1, ' [can not display advanced sequencer table] ');
          end
          
          app.infodisp(1);
          disp('blub');
        end


        % ------------------------------------------------------update 'normal' sequencer table-------------------------------------------------------- 
        function update_std_sequence_table(app)
          app.infodisp(0);
          
          %set variable  for timeout
          startTime = datetime;
          
          % get seq table
          if app.qctk_selected
            app.qctk_stdSeqTable = qc.get_sequence_table(app.program_name, false, [], false, true);
          else
            app.qctk_stdSeqTable = qc.get_sequence_table_from_simulator(app.program_name, false, [], false, true);
          end
          
          % clear table and check for conditions
          app.UIsequencerTable.Data = [];
          dispConditions = ...
             ~isempty(app.selected_sub_seq_table) ...
             && length(app.qctk_stdSeqTable{app.channel_pair_index}) >= app.selected_sub_seq_table ...
             && ~isempty(app.qctk_stdSeqTable) ...
             && ~isempty(app.qctk_stdSeqTable{app.channel_pair_index}) ...
             && ~isempty(app.qctk_stdSeqTable{app.channel_pair_index}{app.selected_sub_seq_table});
          
          % disp seq table in ap
          if dispConditions
              
            % the following loop prevents displaying same entries twice in a row (instead counter in 4th column)
            tmpEntry = app.qctk_stdSeqTable{app.channel_pair_index}{app.selected_sub_seq_table}{1};
            counter = 0;
            line_counter = 1;
            for k = 1:length(app.qctk_stdSeqTable{app.channel_pair_index}{app.selected_sub_seq_table})
              currentTime = datetime;
              if (currentTime-startTime) > app.timeoutTime
                  app.infodisp(0, ' [timeout: did not loaded all Sequencer Table entries] ')
                  break
              end
              if tmpEntry == app.qctk_stdSeqTable{app.channel_pair_index}{app.selected_sub_seq_table}{k}
                counter = counter+1;
              else
                app.UIsequencerTable.Data(line_counter,1:3) = cell2mat(util.py.py2mat(tmpEntry));
                app.UIsequencerTable.Data(line_counter,4) = counter;
                tmpEntry = app.qctk_stdSeqTable{app.channel_pair_index}{app.selected_sub_seq_table}{k};
                counter = 1;
                line_counter = line_counter + 1;
              end
            end
            app.UIsequencerTable.Data(line_counter,1:3) = cell2mat(util.py.py2mat(tmpEntry));
            app.UIsequencerTable.Data(line_counter,4) = counter; 
            
          else
            app.infodisp(0, ' [could not update sequencer table] ');             
          end
          
          app.infodisp(1);
        end


        % ------------------------------------------------------------------update waveform------------------------------------------------------    
        function update_waveforms(app)
            app.infodisp(0);
            
            %disp waveform
            if ~app.qctk_selected
                % for simulator
                cla(app.UIAxes);
                
                [wf1, wf2] = qc.get_segment_waveform(app.program_name, app.channel_pair_index, app.selected_sub_seq_table_entry);
                
                if ~(app.AWGVmultiplierEditField.Value == 0)
                    wf1 = wf1*app.AWGVmultiplierEditField.Value;
                    wf2 = wf2*app.AWGVmultiplierEditField.Value;
                end
                
                timeArray = 1:max(numel(wf1), numel(wf2));                
                if ~(app.AWGtmultiplierEditField.Value == 0)
                    timeArray = timeArray*app.AWGtmultiplierEditField.Value;
                end
                
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, timeArray, wf1);
                plot(app.UIAxes, timeArray, wf2);
                hold(app.UIAxes, 'on');
                
                app.UIAxes.XLabel.String = 'time';
                app.UIAxes.YLabel.String = 'voltage';
                legend(app.UIAxes, 'chan1', 'chan2');
                
            else
                % no displaying for qctk (not simulator)
                drawnow;
            end
            
            app.infodisp(1);
        end


        % ------------------------------------------------------------------export waveform as figure-----------------------------------------
        function exportWaveformToFigure(app)
           app.infodisp(0);
           
           % check if there is actually a plot
           if ~isempty(get(app.UIAxes, 'Children'))
               figure();
               hold on;
               for k = 1:numel(app.UIAxes.Children)
                   x = app.UIAxes.Children(k).XData;
                   y = app.UIAxes.Children(k).YData;
                   plot(x,y);
               end
               hold off;
               legend('chan1','chan2');
               xlabel('time');
               ylabel('voltage');
           end
           
           app.infodisp(1);
        end


    end
   %##########################################################################################################################################################

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.infodisp(0);
            
            global plsdata;
            app.chanAB_selected = true;
            app.qctk_selected = true;
            
            app.AWGtmultiplierEditField.Value = app.AWG_t_multiplier_standard;
            app.AWGVmultiplierEditField.Value = app.AWG_V_multiplier_standard;

            awg_channel_pair_identifiers = {'AB', 'CD'};
            app.hws = plsdata.awg.hardwareSetup;
            app.known_awgs = util.py.py2mat(app.hws.known_awgs);
            sort_indices = cellfun(@(x)(find(  cellfun(@(y)(~isempty(strfind(char(x.identifier), y))), awg_channel_pair_identifiers)  )), app.known_awgs);
            app.known_awgs = app.known_awgs(sort_indices);
            
            app.infodisp(1);
            
            app.update_program_table();
        end

        % Callback function
        function fetchandupdateButtonPushed(app, event)
            disp('Button has been deleted!');
        end

        % Cell selection callback: UIprogramTable
        function UIprogramTableCellSelection(app, event)
            selectedIndex = event.Indices(1);
            app.program_name = app.known_programs{selectedIndex};
            app.update_adv_sequence_table();
            app.UIsequencerTable.Data = [];
            cla(app.UIAxes);
            disp('bla');
        end

        % Selection changed function: channelpairButtonGroup
        function channelpairButtonGroupSelectionChanged(app, event)
            selectedButton = app.channelpairButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'AB')
                app.chanAB_selected = true;
            else
                app.chanAB_selected = false;
            end
            app.update_program_table();
            app.UIadvSequencerTable.Data = [];
            app.UIsequencerTable.Data = [];
            cla(app.UIAxes);
        end

        % Callback function
        function sequencetablesButtonGroupSelectionChanged(app, event)
            disp('Button group has been deleted!');
        end

        % Selection changed function: sourceButtonGroup
        function sourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.sourceButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'qctk')
                app.qctk_selected = true;
            else
                app.qctk_selected = false;
            end
            app.UIadvSequencerTable.Data = [];
            app.UIsequencerTable.Data = [];
            cla(app.UIAxes);
            app.UIprogramTable.Data = [];
            app.update_program_table();
        end

        % Cell selection callback: UIadvSequencerTable
        function UIadvSequencerTableCellSelection(app, event)
            app.selected_sub_seq_table = event.Source.Data(event.Indices(1), 2);
            app.update_std_sequence_table();
        end

        % Cell selection callback: UIsequencerTable
        function UIsequencerTableCellSelection(app, event)
            app.selected_sub_seq_table_entry = event.Source.Data(event.Indices(1), 2);
            app.update_waveforms();
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            app.infodisp(0);
            
            global plsdata;

            awg_channel_pair_identifiers = {'AB', 'CD'};
            app.hws = plsdata.awg.hardwareSetup;
            app.known_awgs = util.py.py2mat(app.hws.known_awgs);
            sort_indices = cellfun(@(x)(find(  cellfun(@(y)(~isempty(strfind(char(x.identifier), y))), awg_channel_pair_identifiers)  )), app.known_awgs);
            app.known_awgs = app.known_awgs(sort_indices);
            
            app.infodisp(1);
            
            app.UIprogramTable.Data = [];
            app.update_program_table();
        end

        % Value changed function: AWGVmultiplierEditField
        function AWGVmultiplierEditFieldValueChanged(app, event)
            value = app.AWGVmultiplierEditField.Value;
            if ~isempty(get(app.UIAxes, 'Children'))
                app.update_waveforms();
            end
        end

        % Value changed function: AWGtmultiplierEditField
        function AWGtmultiplierEditFieldValueChanged(app, event)
            value = app.AWGtmultiplierEditField.Value;
            if ~isempty(get(app.UIAxes, 'Children'))
                app.update_waveforms();
            end
        end

        % Button pushed function: getfigureButton
        function getfigureButtonPushed(app, event)
            app.exportWaveformToFigure();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWGMONITORAPPUIFigure = uifigure;
            app.AWGMONITORAPPUIFigure.Name = 'AWG MONITOR APP';
            app.UIAxes = uiaxes(app.AWGMONITORAPPUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIadvSequencerTable = uitable(app.AWGMONITORAPPUIFigure);
            app.UIadvSequencerTable.ColumnName = {'repitition count'; 'element number'; 'jump flag'; ''};
            app.UIadvSequencerTable.RowName = {};
            app.UIadvSequencerTable.CellSelectionCallback = createCallbackFcn(app, @UIadvSequencerTableCellSelection, true);
            app.sourceButtonGroup = uibuttongroup(app.AWGMONITORAPPUIFigure);
            app.sourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @sourceButtonGroupSelectionChanged, true);
            app.sourceButtonGroup.Title = 'source';
            app.qctkButton = uiradiobutton(app.sourceButtonGroup);
            app.qctkButton.Text = 'qctk';
            app.qctkButton.Value = true;
            app.simulatorButton = uiradiobutton(app.sourceButtonGroup);
            app.simulatorButton.Text = 'simulator';
            app.statusEditFieldLabel = uilabel(app.AWGMONITORAPPUIFigure);
            app.statusEditFieldLabel.Text = 'status';
            app.statusEditField = uieditfield(app.AWGMONITORAPPUIFigure, 'text');
            app.statusEditField.Editable = 'off';
            app.UIprogramTable = uitable(app.AWGMONITORAPPUIFigure);
            app.UIprogramTable.ColumnName = {'program name'};
            app.UIprogramTable.RowName = {};
            app.UIprogramTable.CellSelectionCallback = createCallbackFcn(app, @UIprogramTableCellSelection, true);
            app.channelpairButtonGroup = uibuttongroup(app.AWGMONITORAPPUIFigure);
            app.channelpairButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @channelpairButtonGroupSelectionChanged, true);
            app.channelpairButtonGroup.Title = 'channel pair';
            app.ABButton = uiradiobutton(app.channelpairButtonGroup);
            app.ABButton.Text = 'AB';
            app.ABButton.Value = true;
            app.CDButton = uiradiobutton(app.channelpairButtonGroup);
            app.CDButton.Text = 'CD';
            app.UIsequencerTable = uitable(app.AWGMONITORAPPUIFigure);
            app.UIsequencerTable.ColumnName = {'repetitions count'; 'element number'; 'jump flag'; 'how often actually in table'};
            app.UIsequencerTable.RowName = {};
            app.UIsequencerTable.CellSelectionCallback = createCallbackFcn(app, @UIsequencerTableCellSelection, true);
            app.AdvancedSequencerTableLabel = uilabel(app.AWGMONITORAPPUIFigure);
            app.AdvancedSequencerTableLabel.Text = 'Advanced Sequencer Table';
            app.SelectedTableinSequencerTablesLabel = uilabel(app.AWGMONITORAPPUIFigure);
            app.SelectedTableinSequencerTablesLabel.Text = 'Selected Table in Sequencer Tables';
            app.refreshButton = uibutton(app.AWGMONITORAPPUIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.AWGVmultiplierLabel = uilabel(app.AWGMONITORAPPUIFigure);
            app.AWGVmultiplierLabel.Text = 'AWG V multiplier:';
            app.AWGVmultiplierEditField = uieditfield(app.AWGMONITORAPPUIFigure, 'numeric');
            app.AWGVmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @AWGVmultiplierEditFieldValueChanged, true);
            app.AWGtmultiplierLabel = uilabel(app.AWGMONITORAPPUIFigure);
            app.AWGtmultiplierLabel.Text = 'AWG t multiplier:';
            app.AWGtmultiplierEditField = uieditfield(app.AWGMONITORAPPUIFigure, 'numeric');
            app.AWGtmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @AWGtmultiplierEditFieldValueChanged, true);
            app.getfigureButton = uibutton(app.AWGMONITORAPPUIFigure, 'push');
            app.getfigureButton.ButtonPushedFcn = createCallbackFcn(app, @getfigureButtonPushed, true);
            app.getfigureButton.Text = 'get figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = awgdisp_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AWGMONITORAPPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWGMONITORAPPUIFigure)
        end
    end
end