classdef awgdisp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIsequencerTable           matlab.ui.control.Table
        sourceButtonGroup          matlab.ui.container.ButtonGroup
        qctkButton                 matlab.ui.control.RadioButton
        simulatorButton            matlab.ui.control.RadioButton
        statusEditFieldLabel       matlab.ui.control.Label
        statusEditField            matlab.ui.control.EditField
        sequencetablesButtonGroup  matlab.ui.container.ButtonGroup
        advancedButton             matlab.ui.control.RadioButton
        standardButton             matlab.ui.control.RadioButton
        UIprogramTable             matlab.ui.control.Table
        channelpairButtonGroup     matlab.ui.container.ButtonGroup
        ABButton                   matlab.ui.control.RadioButton
        CDButton                   matlab.ui.control.RadioButton
        fetchandupdateButton       matlab.ui.control.Button
    end


    properties (Access = private)
        hws; %hardware setup
        known_awgs;
        known_programs;
        channel_pair_index;
        program_name;
        
        qctk_advSeqTale;
        qctk_stdSeqTale;
        sim_advSeqTale;
        sim_stdSeqTale;
    end

    methods (Access = private)
    
        function fetch_qctk_seq_table(app, programName, tableType)
            disp('bla');
%             switch tableType
%                 case 'adv'
%                     app.qctk_advSeqTale = qc.get_sequence_table(
        end
        
        % update program table
        function update_program_table(app)
          % get data
          if app.ABButton.Enable
            app.channel_pair_index = 1;
          else
            app.channel_pair_index = 2;
          end
          app.known_programs{app.channel_pair_index} = util.py.py2mat(py.getattr(app.known_awgs{app.channel_pair_index}, '_known_programs'));
          app.known_programs = fieldnames(app.known_programs{app.channel_pair_index});
          %disp data
          app.UIprogramTable.Data = app.known_programs;
        end
        
        function update_sequence_table(app)
            disp('Im here');
            if app.qctkButton.Enable
                if app.advancedButton.Enable
                    app.qctk_advSeqTale = qc.get_sequence_table(app.program_name, true);
                    for k = a:length(app.qctk_advSeqTale)
                        app.UIsequencerTable.Data(k,:) = cell2mat(app.qctk_advSeqTale{app.channel_pair_index}{k});
                    end
                else
                    app.qctk_advSeqTale = get_sequence_table(app.program_name, false);
                end
            else
                if app.advancedButton.Enable
                    warning('not yet implemented');
                else
                    warning('not yet implemented');
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global plsdata;
            awg_channel_pair_identifiers = {'AB', 'CD'};
            app.hws = plsdata.awg.hardwareSetup;
            known_awgs = util.py.py2mat(app.hws.known_awgs);
            sort_indices = cellfun(@(x)(find(  cellfun(@(y)(~isempty(strfind(char(x.identifier), y))), awg_channel_pair_identifiers)  )), known_awgs);
            app.known_awgs = known_awgs(sort_indices);
        end

        % Button pushed function: fetchandupdateButton
        function fetchandupdateButtonPushed(app, event)
            app.update_program_table();
        end

        % Cell selection callback: UIprogramTable
        function UIprogramTableCellSelection(app, event)
            disp('bla');
            selectedIndex = event.Indices(1);
            app.program_name = app.known_programs{selectedIndex};
            app.update_sequence_table();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIsequencerTable = uitable(app.UIFigure);
            app.UIsequencerTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIsequencerTable.RowName = {};
            app.sourceButtonGroup = uibuttongroup(app.UIFigure);
            app.sourceButtonGroup.Title = 'source';
            app.qctkButton = uiradiobutton(app.sourceButtonGroup);
            app.qctkButton.Text = 'qctk';
            app.qctkButton.Value = true;
            app.simulatorButton = uiradiobutton(app.sourceButtonGroup);
            app.simulatorButton.Text = 'simulator';
            app.statusEditFieldLabel = uilabel(app.UIFigure);
            app.statusEditFieldLabel.Text = 'status';
            app.statusEditField = uieditfield(app.UIFigure, 'text');
            app.statusEditField.Editable = 'off';
            app.sequencetablesButtonGroup = uibuttongroup(app.UIFigure);
            app.sequencetablesButtonGroup.Title = 'sequence tables';
            app.advancedButton = uiradiobutton(app.sequencetablesButtonGroup);
            app.advancedButton.Text = 'advanced';
            app.advancedButton.Value = true;
            app.standardButton = uiradiobutton(app.sequencetablesButtonGroup);
            app.standardButton.Text = 'standard';
            app.UIprogramTable = uitable(app.UIFigure);
            app.UIprogramTable.ColumnName = {'program name'};
            app.UIprogramTable.RowName = {};
            app.UIprogramTable.CellSelectionCallback = createCallbackFcn(app, @UIprogramTableCellSelection, true);
            app.channelpairButtonGroup = uibuttongroup(app.UIFigure);
            app.channelpairButtonGroup.Title = 'channel pair';
            app.ABButton = uiradiobutton(app.channelpairButtonGroup);
            app.ABButton.Text = 'AB';
            app.ABButton.Value = true;
            app.CDButton = uiradiobutton(app.channelpairButtonGroup);
            app.CDButton.Text = 'CD';
            app.fetchandupdateButton = uibutton(app.UIFigure, 'push');
            app.fetchandupdateButton.ButtonPushedFcn = createCallbackFcn(app, @fetchandupdateButtonPushed, true);
            app.fetchandupdateButton.Text = 'fetch and update';
        end
    end

    methods (Access = public)

        % Construct app
        function app = awgdisp_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end